classdef Projeto_APIv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ColourPaletteGenerator          matlab.ui.Figure
        Image                           matlab.ui.control.UIAxes
        SelectImageButton               matlab.ui.control.Button
        NumberofColoursinPaletteSliderLabel  matlab.ui.control.Label
        NumberofColoursinPaletteSlider  matlab.ui.control.Slider
        GeneratePaletteButton           matlab.ui.control.Button
        ExportPaletteButton             matlab.ui.control.Button
    end


    properties (Access = private)
        ImageSelected;
        Palette;
        NumberColours = 1;
    end


    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [filename,path] = uigetfile({'*.jpg; *.png; *.tif'});   %reads image from directory
            cd (path);
            im = strsplit(filename, '.');
            image = imread(filename, im{end});
            imshow(image,'Parent',app.Image,'InitialMagnification','fit');
            app.ImageSelected = image;                                  %assigns a value to the app property ImageSelected
        end

        % Value changed function: NumberofColoursinPaletteSlider
        function NumberofColoursinPaletteSliderValueChanged(app, event)
            value = app.NumberofColoursinPaletteSlider.Value;      %reads slider value 
            N_palette = round(value);                              %rounds the slider value to an integer
            app.NumberofColoursinPaletteSlider.Value = N_palette;  %displays the rounded value in the GUI
            app.NumberColours = N_palette;                         %assigns a value to the app property NumberColours
        end

        % Button pushed function: GeneratePaletteButton
        function GeneratePaletteButtonPushed(app, event)
            image = app.ImageSelected;
            N_palette = app.NumberColours; %defines the number of clusters
            
            im_converted = rgb2lab(image); %converts from RGB to the L*a*b colour space
            [m,n,d] = size(image);
            N = m*n;
            X = reshape(im_converted,N,d); %reshapes to a 2D matrix
            
            idx = kmeans(X, N_palette); %uses the k-means method to find different clusters of colours
            idx_reshape = reshape(idx, m, n, 1); %reshapes to a 2D matrix with the same m-n of the original image
            
            image_seg = image;
            colours = uint8(zeros(300,80*N_palette,3));
            colour_code = {};
            colour_ind = 0;
            
            for i = 1:N_palette
    
                code = '#';
                %segments the original image
                mask = (idx_reshape == i);
                image_seg(:,:,1) = double(image(:,:,1)).*mask;
                image_seg(:,:,2) = double(image(:,:,2)).*mask;
                image_seg(:,:,3) = double(image(:,:,3)).*mask;
    
                %calculates the average value of colour in each segmented image
                for j = 1:3
                    tot = 0;
                    n_vals = 0;
                    for k = 1:m
                        for l = 1:n
                            if image_seg(k,l,j) ~= 0
                                tot = tot + double(image_seg(k,l,j));
                                n_vals = n_vals + 1;
                            end
                        end
                    end
                    col_med = tot/n_vals;
                    colours(:,1+80*colour_ind:80+80*colour_ind,j) = uint8(col_med);
                    code = strcat(code,dec2hex(uint8(col_med),2)); %converts the obtained colour values to their html code
                end
    
                colour_code{i} = code;
                colour_ind = colour_ind +1;
    
            end
            
            text_positions = [18 260];
            
            for ind = 1:N_palette-1
                 text_positions = [text_positions; [18+80*ind 260]];
            end
            
            colours = insertText(colours,text_positions,colour_code,'FontSize',10,'BoxColor','white','TextColor','black');
            app.Palette = colours;
            
            figure ('name','Colour Palette','NumberTitle','off')
            imshow(colours);
        end

        % Button pushed function: ExportPaletteButton
        function ExportPaletteButtonPushed(app, event)
            colours = app.Palette;
            [SaveFile, path] = uiputfile({'*.jpg';'*.png';'*.tif'}); %chooses which file to save to
            cd (path)
            im = strsplit(SaveFile, '.');
            imwrite(colours,SaveFile,im{end}); %saves the colour palette to a file
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ColourPaletteGenerator = uifigure;
            app.ColourPaletteGenerator.Name = 'Colour Palette Generator';
            app.Image = uiaxes(app.ColourPaletteGenerator);
            title(app.Image, 'Image')
            app.Image.XTick = [];
            app.Image.YTick = [];
            app.SelectImageButton = uibutton(app.ColourPaletteGenerator, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.NumberofColoursinPaletteSliderLabel = uilabel(app.ColourPaletteGenerator);
            app.NumberofColoursinPaletteSliderLabel.Text = 'Number of Colours in Palette';
            app.NumberofColoursinPaletteSlider = uislider(app.ColourPaletteGenerator);
            app.NumberofColoursinPaletteSlider.Limits = [1 20];
            app.NumberofColoursinPaletteSlider.MajorTicks = [1 5 10 15 20];
            app.NumberofColoursinPaletteSlider.MajorTickLabels = {'1', '5', '10', '15', '20'};
            app.NumberofColoursinPaletteSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofColoursinPaletteSliderValueChanged, true);
            app.NumberofColoursinPaletteSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.NumberofColoursinPaletteSlider.Value = 1;
            app.GeneratePaletteButton = uibutton(app.ColourPaletteGenerator, 'push');
            app.GeneratePaletteButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePaletteButtonPushed, true);
            app.GeneratePaletteButton.Text = 'Generate Palette';
            app.ExportPaletteButton = uibutton(app.ColourPaletteGenerator, 'push');
            app.ExportPaletteButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPaletteButtonPushed, true);
            app.ExportPaletteButton.Text = 'Export Palette';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Projeto_APIv2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ColourPaletteGenerator)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ColourPaletteGenerator)
        end
    end
end